classdef main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        PulseSettingsTab               matlab.ui.container.Tab
        LaserVoltageVEditFieldLabel    matlab.ui.control.Label
        LaserVoltageVEditField         matlab.ui.control.NumericEditField
        PulseWidthnsEditFieldLabel     matlab.ui.control.Label
        PulseWidthnsEditField          matlab.ui.control.NumericEditField
        DelaysLabel                    matlab.ui.control.Label
        DelaysEditField                matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        AmorphizationLabel             matlab.ui.control.Label
        CrystallizationLabel           matlab.ui.control.Label
        DutyCycleLabel                 matlab.ui.control.Label
        DutyCycleEditField             matlab.ui.control.NumericEditField
        FrequencykHzEditFieldLabel     matlab.ui.control.Label
        FrequencykHzEditField          matlab.ui.control.NumericEditField
        DutyCycleEditField_2           matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.StateButton
        PeriodsEditFieldLabel          matlab.ui.control.Label
        PeriodsEditField               matlab.ui.control.NumericEditField
        Image                          matlab.ui.control.Image
        EditField_46                   matlab.ui.control.NumericEditField
        EditField_47                   matlab.ui.control.NumericEditField
        EditField_48                   matlab.ui.control.NumericEditField
        EditField_49                   matlab.ui.control.NumericEditField
        EditField_50                   matlab.ui.control.NumericEditField
        ReadPulseLabel                 matlab.ui.control.Label
        DutyCycleEditField_3           matlab.ui.control.NumericEditField
        EditField_51                   matlab.ui.control.NumericEditField
        EditField_52                   matlab.ui.control.NumericEditField
        MonitoringTab                  matlab.ui.container.Tab
        ControllerDropDownLabel        matlab.ui.control.Label
        ControllerDropDown             matlab.ui.control.DropDown
        DCDCConverterDropDownLabel     matlab.ui.control.Label
        DCDCConverterDropDown          matlab.ui.control.DropDown
        PowerConsumptionWGaugeLabel    matlab.ui.control.Label
        PowerConsumptionWGauge         matlab.ui.control.SemicircularGauge
        HostnameEditFieldLabel         matlab.ui.control.Label
        HostnameEditField              matlab.ui.control.EditField
        RaspberryPiLabel               matlab.ui.control.Label
        UsernameEditFieldLabel         matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        IPAddressEditFieldLabel        matlab.ui.control.Label
        IPAddressEditField             matlab.ui.control.EditField
        CameraResolutionDropDownLabel  matlab.ui.control.Label
        CameraResolutionDropDown       matlab.ui.control.DropDown
        TestCameraButton               matlab.ui.control.Button
        EnvironmentTab                 matlab.ui.container.Tab
        AmbientTemperatureCLabel       matlab.ui.control.Label
        AmbientTemperatureCGauge       matlab.ui.control.LinearGauge
        HumidityGaugeLabel             matlab.ui.control.Label
        HumidityGauge                  matlab.ui.control.LinearGauge
        PressurepsiGaugeLabel          matlab.ui.control.Label
        PressurepsiGauge               matlab.ui.control.LinearGauge
        InstrumentsTab                 matlab.ui.container.Tab
        USBRelayDropDownLabel          matlab.ui.control.Label
        USBRelayDropDown               matlab.ui.control.DropDown
        ResourceDropDownLabel          matlab.ui.control.Label
        ResourceDropDown               matlab.ui.control.DropDown
        DriverDropDownLabel            matlab.ui.control.Label
        DriverDropDown                 matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        DisconnectButton               matlab.ui.control.Button
        OscilloscopeChannelDropDownLabel  matlab.ui.control.Label
        OscilloscopeChannelDropDown    matlab.ui.control.DropDown
        FnctnGenChannelDropDownLabel   matlab.ui.control.Label
        FnctnGenChannelDropDown        matlab.ui.control.DropDown
        OscVerticalRangeVEditFieldLabel  matlab.ui.control.Label
        OscVerticalRangeVEditField     matlab.ui.control.NumericEditField
        AquisitionTimesEditFieldLabel  matlab.ui.control.Label
        AquisitionTimesEditField       matlab.ui.control.NumericEditField
        AutoSetupButton                matlab.ui.control.StateButton
        TunableFilterReliabilityMeasurementLabel  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        MeasurementModeTab             matlab.ui.container.Tab
        TotalCyclesEditFieldLabel      matlab.ui.control.Label
        TotalCyclesEditField           matlab.ui.control.NumericEditField
        TotalTimeminEditFieldLabel     matlab.ui.control.Label
        TotalTimeminEditField          matlab.ui.control.NumericEditField
        SaveeveryEditFieldLabel        matlab.ui.control.Label
        SaveeveryEditField             matlab.ui.control.NumericEditField
        cyclesLabel                    matlab.ui.control.Label
        SaveeveryEditField_2Label      matlab.ui.control.Label
        SaveeveryEditField_2           matlab.ui.control.NumericEditField
        minutesLabel                   matlab.ui.control.Label
        LimittheButtonGroup            matlab.ui.container.ButtonGroup
        totalcyclesButton              matlab.ui.control.RadioButton
        totaltimeButton                matlab.ui.control.RadioButton
        bothButton                     matlab.ui.control.RadioButton
        StopConditionTab               matlab.ui.container.Tab
        ofoccurancesoutsideoflimitCheckBox  matlab.ui.control.CheckBox
        NumberofoccurancesoutsideoflimitCheckBox  matlab.ui.control.CheckBox
        AmountoutsideoflimitCheckBox   matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_4Label_2             matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        DropDown                       matlab.ui.control.DropDown
        TransmittanceCheckBox          matlab.ui.control.CheckBox
        CenterWavelengthCheckBox       matlab.ui.control.CheckBox
        FWHMCheckBox                   matlab.ui.control.CheckBox
        StopbasedonLabel               matlab.ui.control.Label
        LimitsTab                      matlab.ui.container.Tab
        GridLayout2_2                  matlab.ui.container.GridLayout
        AmorphizationLabel_3           matlab.ui.control.Label
        CrystalizationLabel_2          matlab.ui.control.Label
        EditField_34                   matlab.ui.control.NumericEditField
        EditField_35                   matlab.ui.control.NumericEditField
        EditField_36                   matlab.ui.control.NumericEditField
        EditField_37                   matlab.ui.control.NumericEditField
        EditField_38                   matlab.ui.control.NumericEditField
        EditField_39                   matlab.ui.control.NumericEditField
        EditField_40                   matlab.ui.control.NumericEditField
        EditField_41                   matlab.ui.control.NumericEditField
        EditField_42                   matlab.ui.control.NumericEditField
        EditField_43                   matlab.ui.control.NumericEditField
        EditField_44                   matlab.ui.control.NumericEditField
        EditField_45                   matlab.ui.control.NumericEditField
        MeasuredLabel_2                matlab.ui.control.Label
        AverageLabel_3                 matlab.ui.control.Label
        LimitLabel_2                   matlab.ui.control.Label
        AverageLabel_4                 matlab.ui.control.Label
        DeviationLabel_2               matlab.ui.control.Label
        MaxLabel_2                     matlab.ui.control.Label
        MinLabel_2                     matlab.ui.control.Label
        ConfigurationLabel_2           matlab.ui.control.Label
        TestingTab                     matlab.ui.container.Tab
        Switch                         matlab.ui.control.ToggleSwitch
        GetFTIRdataButton              matlab.ui.control.Button
        TransmittanceEditFieldLabel    matlab.ui.control.Label
        TransmittanceEditField         matlab.ui.control.NumericEditField
        FilesTab                       matlab.ui.container.Tab
        FoldertoSaveFilesButton        matlab.ui.control.StateButton
        EditField_53                   matlab.ui.control.EditField
        FTIRDataFolderEditField        matlab.ui.control.EditField
        FTIRFileNameEditField          matlab.ui.control.EditField
        SaveddatafilenameEditFieldLabel  matlab.ui.control.Label
        SaveddatafilenameEditField     matlab.ui.control.EditField
        FoldertoLoadFilesButton        matlab.ui.control.StateButton
        FoldertoLoadFilesButton_2      matlab.ui.control.StateButton
        IRCamDataFolderEditField       matlab.ui.control.EditField
        IRCamFileNameEditField         matlab.ui.control.EditField
        SaveddatafilenameEditFieldLabel_2  matlab.ui.control.Label
        SaveddatafilenameEditFieldLabel_3  matlab.ui.control.Label
        Switch_2                       matlab.ui.control.Switch
        DrawROIButton                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        TransmittanceTab               matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        CenterWavelengthTab            matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        FWHMTab                        matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        FTIRTab                        matlab.ui.container.Tab
        UIAxes3_2                      matlab.ui.control.UIAxes
        TabGroup4                      matlab.ui.container.TabGroup
        LaserOscFGenTab                matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        IRImageTab                     matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Property % Description
        folder
        mypi
        cam
        masks = load('roi.mat','mask_1','mask_2','mask_3','mask_4','mask_5','mask_6');
        roi = load('roi.mat','roi_1','roi_2','roi_3','roi_4','roi_5','roi_6');
        roi1
        roi2
        roi3
        roi4
        roi5
        roi6
        mask1
        mask2
        mask3
        mask4
        mask5
        mask6
        cycle
        ftir_file_nmbr % Numbering of the FTIR files
        ats_file_nmbr % number of the IR camera files
    end
    
    properties (Access = private)
        t1 % timer object for the FTIR
        t2 % timer object for the IR camera
    end
    
    methods (Access = private)
        
        function frame = import_ir_gui(app,filename)
            ir_folder = app.IRCamDataFolderEditField.Value;
            try
                v = FlirMovieReader([ir_folder,'\',filename, '_',num2str(app.ats_file_nmbr),'.ats']); % open file
                app.ats_file_nmbr = app.ats_file_nmbr + 1;
                
            catch
                v = FlirMovieReader([ir_folder,'\', filename, '_',num2str(app.ats_file_nmbr-1),'.ats']); % open file
                warning('Displaying last ATS file; may not be current');
               
            end
            [frame, ~] = step(v); delete(v);
            imshow(frame,'parent',app.UIAxes4);
            colormap(app.UIAxes4,'parula');
            caxis(app.UIAxes4,[min(frame,[],'all') max(frame,[],'all')]);
        end
        
        
        function ncd = relay_startup(~,com_nmbr)
            ncd=serialport(com_nmbr); % assign serial port object
            set(ncd, 'BaudRate', 115200); % set BaudRate to 9600
            set(ncd, 'Parity', 'none'); % set Parity Bit to None
            set(ncd, 'DataBits', 8); % set DataBits to 8
            set(ncd, 'StopBit', 1); % set StopBit to 1
            %display the properties of serial port object in MATLAB Window
            disp(get(ncd,{'Type','Name','Port','BaudRate','Parity','DataBits','StopBits'}));
            fopen(ncd);           % Open Serial Port Object
            fwrite(ncd,254);      % ProXR Header Byte
        end
        
        function relay_trigger(~)
            fwrite(ncd,254);      % ProXR Header Byte
            fwrite(ncd,108);      % Turn on Relay 1
            fwrite(ncd,1);        % in Relay Bank 1
            %disp(fread(ncd,1));   % Read Confirmation Data
            %disp('Relay Is On');  % Tell User Relay has Activated
            fwrite(ncd,100);      % Turn off Relay 1
            fwrite(ncd,1);        % in Relay Bank 1
            %disp(fread(ncd,1));   % Read Confirmation Data
            %disp('Relay Is OFF'); % Tell User Relay has Activated
            fclose(ncd);          % Close Serial Port Object
        end
        
        function timer_fcn(~, ~, event)
            event_type = event.Type;
            fprintf(event_type);
            
        end
        
        function [lambda0, lambda1, lambda2] = ftir_plotting(app,csv_file)
            ftir_folder = app.FTIRDataFolderEditField.Value;
%             try
                A = readmatrix([ftir_folder,'\',csv_file,'_',num2str(app.ftir_file_nmbr),'.CSV']);
                app.ftir_file_nmbr = app.ftir_file_nmbr + 1;
%             catch
%                 A = readmatrix([ftir_folder,'\',csv_file,'_',num2str(app.ftir_file_nmbr-1),'.CSV']);
%             end
            lambda = A(:,1);
            T = A(:,2); % transmittance
            % Find the half max value.
            halfMax = (min(T) + max(T)) / 2;
            % Find where the data first drops below half the max.
            index1 = find(T >= halfMax, 1, 'first');
            % Find where the data last rises above half the max.
            index2 = find(T >= halfMax, 1, 'last');
            
            % Maximum transmittance
            maxT = max(T); minT = min(T);
            
            % Find the center wavelength
            lambda1 = lambda(index1); lambda2 = lambda(index2);
            lambda0 = 2*lambda1*lambda2/(lambda1 + lambda2); % center wavlength
            for j = 1:find(T == maxT)
                if T(j) > 0.1
                    a1 = lambda(j-1);
                    break
                end
            end
            for k = find(T == maxT) + 1: length(T)
                if T(k) <= 0.1
                    a2 = lambda(k);
                    break
                end
            end
            
            plot(app.UIAxes3_2, lambda, T,'k');
            hold(app.UIAxes3_2,'on')
            xlim(app.UIAxes3_2, [min(lambda), 4]);
            ylim(app.UIAxes3_2, [0 125])
            xlabel(app.UIAxes3_2, 'Wavelength ({\mu}m)');
            ylabel(app.UIAxes3_2, '% Transmittance');
            plot(app.UIAxes3_2, lambda(T == maxT),maxT,'or'); % peak transmittance
            plot(app.UIAxes3_2, [lambda0 lambda0], [minT maxT],'b'); % center wavelength
            plot(app.UIAxes3_2, [a1, a1], [0 maxT],'g'); % attenuation
            plot(app.UIAxes3_2, [a2, a2], [0 maxT],'g'); % attenuation
            % FWHM
            plot(app.UIAxes3_2, [lambda1 lambda1], [minT T(lambda == lambda1)],'m') % First half-max
            plot(app.UIAxes3_2, [lambda2 lambda2], [minT T(lambda == lambda2)],'m') % Second half-max
            legend(app.UIAxes3_2,...
                'Transmittance',['Peak Transmittance: ',num2str(maxT),'%'],...
                ['Center Wavlength: ', num2str(lambda0), ' {\mu}m'], 'Attenuation',...
                '','FWHM','','Location','northwest','NumColumns',2);
            hold(app.UIAxes3_2,'off');
        end
        
        function cw_fwhm_tr(app, lambda0, lambda1, lambda2,tr_fig,cl_fig,fwhm_fig)
            % Center wavelength, full-width half maximum, and transmittance
            % Plot the center wavelength calcualted by FTIR
            %cl_fig = plot(app.UIAxes3,floor(app.cycle./2),lambda0,'o');
            %cl_fig = plot(app.UIAxes3,app.cycle,lambda0,'o');
            %hold(app.UIAxes3, 'on');
            addpoints(cl_fig, app.cycle, lambda0);
            
            % Plot the FWHM calculated by the FTIR
            %fwhm_fig=plot(app.UIAxes5,app.cycle,lambda2-lambda1,'o');
            %hold(app.UIAxes5, 'on');
            addpoints(fwhm_fig, app.cycle, lambda2-lambda1);
            
            % Load the IR Camera Image
            frame = import_ir_gui(app,app.IRCamFileNameEditField.Value);
            
            % Calculate transmittance and plot it
            %----------------------------------------------------------
            % Plot the transmittance calcualted from IR camera.
            m1 = mean2(frame(app.masks.mask_1));
            % m2 = mean2(frame(mask2));
            m3 = (sum(frame(app.masks.mask_3),'all')+sum(frame(app.masks.mask_4),'all') + ...
                sum(frame(app.masks.mask_5),'all')+sum(frame(app.masks.mask_6),'all'))/...
                (numel(frame(app.masks.mask_3))+numel(frame(app.masks.mask_4)) + ...
                numel(frame(app.masks.mask_5)) +numel(frame(app.masks.mask_6)));
            tr1 = m1/m3*100; % transmittance of GST filter
            %tr2 = m2/m3; % transmittance of reference filter
            %tr_fig = plot(app.UIAxes2, app.cycle, tr1,'o');
            addpoints(tr_fig, app.cycle,tr1);
            %hold(app.UIAxes2, 'on');
            
%             if rem(app.cycle,2) == 0
%                 cl_fig.Color = 'red';
%                 fwhm_fig.Color = 'red';
%                 tr_fig.Color = 'red';
%             else
%                 cl_fig.Color = 'blue';
%                 fwhm_fig.Color = 'blue';
%                 tr_fig.Color = 'blue';
%             end
        end
        
        function disable_buttons(app)
            app.IRCamFileNameEditField.Enable = 'off';
            app.FTIRFileNameEditField.Enable = 'off';
            app.SaveddatafilenameEditField.Enable = 'off';
        end
        
        function oscilloscope_startup(app)
            %startupFcn Creates oscilloscope object and initializes app UI with available drivers and resources
        
            % Create quick-control oscilloscope object
            app.Oscilloscope = oscilloscope;
            
            % Get available oscilloscope drivers at start time
            app.DriverDropDown.Items = getDriverNames(app, app.Oscilloscope);
            
            % Get available oscilloscope resources at start time
            % (execute ResourceScanButton callback function)
            ResourceScanButtonPushed(app,[])
            
            % Disable quick-control oscilloscope warnings such as
            % 'The set value 6.00000e+00 is different from instrument value 5.00000e+00.'
            warning('off','instrument:qcinstrument:realValueIsDifferent')
            
            % Configure app UI controls to correspond to instrument "disconnected" state
            % (Connection will be attempted when Connect button is pushed)
            setAppControlsState(app, 'disconnected');    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.cycle = 1;
            
            % For the files and folders
            if(~isdeployed); cd(fileparts(which('main_gui'))); end
            
            %app.folder = pwd;
            %app.folder = convertCharsToStrings(app.folder);
            app.EditField_53.Value = 'saved_data';
            app.FTIRDataFolderEditField.Value = 'ftir_files';
            app.IRCamDataFolderEditField.Value= 'ats_files';
            
            app.SaveddatafilenameEditField.Value = 'test_data';
            app.IRCamFileNameEditField.Value = 'sample';
            app.FTIRFileNameEditField.Value = 'PCM-Filter';
            
            if ~(logical(exist(app.FTIRDataFolderEditField.Value,'file'))) 
                mkdir(app.FTIRDataFolderEditField.Value);
            end
            
            if ~(logical(exist(app.IRCamDataFolderEditField.Value, 'file'))) 
                mkdir(app.IRCamDataFolderEditField.Value);
            end
            
            if ~(logical(exist(app.EditField_53.Value, 'file'))) 
                mkdir(app.EditField_53.Value);
            end
            
            % For the USB Relay
            serial_info = instrhwinfo('serial');
            ports = serial_info.AvailableSerialPorts;
            app.USBRelayDropDown.Items = ports;
            app.ftir_file_nmbr = 1; app.ats_file_nmbr = 1;
            
            % For the Oscilloscope
%             oscilloscope_startup(app);

            % Creating the timer object
            %             app.myTimer = timer('Period',2,...
            %                 'ExecutionMode', 'fixedSpacing', ...
            %                 'TasksToExecute', Inf);
            %             app.myTimer.TimerFcn = @timer_fcn;
            
            % Display one frame of the IR image
            ir_folder = app.IRCamDataFolderEditField.Value;
            filename = app.IRCamFileNameEditField.Value;
            v = FlirMovieReader([ir_folder,'\',filename, '_1.ats']); % open file
            [frame, ~] = step(v); delete(v);
            imshow(frame,'parent',app.UIAxes4);
            colormap(app.UIAxes4,'parula');
            caxis(app.UIAxes4,[min(frame,[],'all') max(frame,[],'all')]);
            
            % Display the ROIs
            h1 = images.roi.Ellipse(app.UIAxes4);
            h2 = images.roi.Ellipse(app.UIAxes4);
            h3 = images.roi.Polygon(app.UIAxes4);
            h4 = images.roi.Polygon(app.UIAxes4);
            h5 = images.roi.Polygon(app.UIAxes4);
            h6 = images.roi.Polygon(app.UIAxes4);
            
            h1.Center = app.roi.roi_1.Center;
            h1.SemiAxes = app.roi.roi_1.SemiAxes; 
            h1.Color = 'red';
            h1.RotationAngle = app.roi.roi_1.RotationAngle; 
            
            h2.Center = app.roi.roi_2.Center;
            h2.SemiAxes = app.roi.roi_2.SemiAxes;
            h2.Color = 'red';
            h2.RotationAngle = app.roi.roi_2.RotationAngle; 
            
            h3.Position = app.roi.roi_3.Position; h3.Color = 'red';
            h4.Position = app.roi.roi_4.Position; h4.Color = 'red';
            h5.Position = app.roi.roi_5.Position; h5.Color = 'red';
            h6.Position = app.roi.roi_6.Position; h6.Color = 'red';
            
            h1.Visible = 'on'; h2.Visible = 'on'; h3.Visible = 'on';
            h4.Visible = 'on'; h5.Visible = 'on'; h6.Visible = 'on';
            
            h1.LineWidth = 0.5;h2.LineWidth = 0.5;h3.LineWidth = 0.5;
            h4.LineWidth = 0.5;h5.LineWidth = 0.5;h6.LineWidth = 0.5;
            
            hold(app.UIAxes4,'on');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {602, 602};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {466, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(value)
            if value == "On"
                %ncd=serial('COM4'); % assign serial port object
                ncd=serialport('COM4'); % assign serial port object
                set(ncd, 'BaudRate', 115200); % set BaudRate to 9600
                set(ncd, 'Parity', 'none'); % set Parity Bit to None
                set(ncd, 'DataBits', 8); % set DataBits to 8
                set(ncd, 'StopBit', 1); % set StopBit to 1
                %display the properties of serial port object in MATLAB Window
                disp(get(ncd,{'Type','Name','Port','BaudRate','Parity','DataBits','StopBits'}));
                fopen(ncd);           % Open Serial Port Object
                fwrite(ncd,254);      % ProXR Header Byte
                fwrite(ncd,108);      % Turn on Relay 1
                fwrite(ncd,1);        % in Relay Bank 1
                disp(fread(ncd,1));   % Read Confirmation Data
                disp('Relay Is On');  % Tell User Relay has Activated
                fclose(ncd);          % Close Serial Port Object
            elseif value == "Off"
                %ncd=serial('COM4'); % assign serial port object
                ncd=serialport('COM4'); % assign serial port object
                set(ncd, 'BaudRate', 115200); % set BaudRate to 9600
                set(ncd, 'Parity', 'none'); % set Parity Bit to None
                set(ncd, 'DataBits', 8); % set DataBits to 8
                set(ncd, 'StopBit', 1); % set StopBit to 1
                %display the properties of serial port object in MATLAB Window
                disp(get(ncd,{'Type','Name','Port','BaudRate','Parity','DataBits','StopBits'}));
                fopen(ncd);           % Open Serial Port Object
                fwrite(ncd,254);      % ProXR Header Byte
                fwrite(ncd,100);      % Turn off Relay 1
                fwrite(ncd,1);        % in Relay Bank 1
                disp(fread(ncd,1));   % Read Confirmation Data
                disp('Relay Is OFF'); % Tell User Relay has Activated
                fclose(ncd);          % Close Serial Port Object
            end
        end

        % Value changed function: EditField_53, 
        % FoldertoSaveFilesButton
        function ChooseFileLocationButtonValueChanged(app, event)
            app.FoldertoSaveFilesButton.Value;
            app.folder = uigetdir;
            app.folder = convertCharsToStrings(app.folder);
            app.EditField_53.Value = app.folder;
        end

        % Value changed function: ConfirmButton
        function ConfirmButtonValueChanged(app, event)
            %value = app.ConfirmButton.Value;
            % The pulse width to change to amorphous state.
            pw_a = app.PulseWidthnsEditField.Value;
            % The wait time after the amorphization pulse.
            delay_a = app.DelaysEditField.Value;
            delay_a = delay_a*1000; % Convert microseconds to nanoseconds
            % The duty cycle
            %ds_a = app.DutyCycleEditField.Value; % duty cycle of amorphization
            app.DutyCycleEditField.Value = pw_a/(pw_a+delay_a)*100;
            
            % Cystallization
            pw_c = app.EditField_2.Value; % amorphization value
            delay_c = app.EditField_3.Value; % amorphization value
            delay_c = delay_c*1000; % Convert microseconds to nanoseconds
            %ds_c = app.DutyCycleEditField.Value; % duty cycle of amorphization
            app.DutyCycleEditField_2.Value = pw_c/(pw_c+delay_c)*100;
            
            % Read Pulse
            pw_r = app.EditField_49.Value;
            delay_r = app.EditField_50.Value*1000;
            
            % Add up the pulse width and delay to get the period
            % in nanoseconds. Multiply by 10^-9 to change the unit to
            % seconds. Then take the reciprocal to get the frequency in
            % Hz. Then multiply by 10^-3 to get frequency in kHz.
            % app.FrequencykHzEditField.Value= (10^-3)/(10^-9*(pw_a+pw_c+delay_a+delay_c));
            
            % Frequency - Amorphization
            app.FrequencykHzEditField.Value = (10^-3)/(10^-9*(pw_a+delay_a));
            
            % Period - Amorphization
            app.PeriodsEditField.Value = (10^-3)*(pw_a+delay_a);
            
            % Frequency - Crystallization
            app.EditField_46.Value = (10^-3)/(10^-9*(pw_c+delay_c));
            
            % Peiod - Crystallization
            app.EditField_47.Value = (10^-3)*(pw_c+delay_c);
            
            % Frequency - Read Pulse
            app.EditField_51.Value = (10^-3)/(10^-9*(pw_r+delay_r));
            
            % Period - Read Pulse
            app.EditField_52.Value = (10^-3)*(pw_r+delay_r);
            
            % Duty Cycle - Read Pulse
            app.DutyCycleEditField_3.Value = pw_r/(pw_r+delay_r)*100;
            
            
            % Add up the two pulse widths and two delays to get the period
            % in nanoseconds. Then multiply by 10^-3 to convert to
            % microseconds.
            % app.PeriodsEditField.Value = (10^-3)*(pw_a+pw_c+delay_a+delay_c);
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: TestCameraButton
        function TestCameraButtonPushed(app, event)
            % clear mypi;
            %         hostname = 'raspberrypi';
            %         username = 'pi';
            %         password = 'nasaLaRCD304';
            %         ipaddress = '169.254.253.170';
            app.mypi = raspi();%ipaddress,username,password) % Use this one.
            % mypi = raspi(hostname,username,password) % Not this one
            app.cam = cameraboard(app.mypi,'Resolution','640x480');
            for i = 1:100
                img = snapshot(app.cam);
                image(app.UIAxes4,img);
                drawnow;
            end
            cla(app.UIAxes4);
        end

        % Button pushed function: GetFTIRdataButton
        function GetFTIRdataButtonPushed(app, event)
            A = readmatrix('PCM-Filter.CSV');
            lambda = A(:,1);
            T = A(:,2); % transmittance
            
            % Find the half max value.
            halfMax = (min(T) + max(T)) / 2;
            % Find where the data first drops below half the max.
            index1 = find(T >= halfMax, 1, 'first');
            % Find where the data last rises above half the max.
            index2 = find(T >= halfMax, 1, 'last');
            %fwhm = index2-index1 + 1; % FWHM in indexes.
            % OR, if you have an x vector
            %fwhmx = lambda(index2) - lambda(index1);
            
            % Maximum transmittance
            maxT = max(T);
            minT = min(T);
            
            % Find the center wavelength
            lambda1 = lambda(index1); lambda2 = lambda(index2);
            lambda0 = 2*lambda1*lambda2/(lambda1 + lambda2); % center wavlength
            
            for i = 1:find(T == maxT)
                if T(i) > 0.1
                    a1 = lambda(i-1);
                    break
                end
            end
            for i = find(T == maxT) + 1: length(T)
                if T(i) <= 0.1
                    a2 = lambda(i);
                    break
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plotting
            plot(app.UIAxes3_2, lambda, T,'k');
            hold(app.UIAxes3_2,'on')
            xlim(app.UIAxes3_2, [min(A(:,1)), 4]);
            ylim(app.UIAxes3_2, [0 125])
            xlabel(app.UIAxes3_2, 'Wavelength ({\mu}m)');
            ylabel(app.UIAxes3_2, '% Transmittance');
            plot(app.UIAxes3_2, lambda(T == maxT),maxT,'or'); % peak transmittance
            plot(app.UIAxes3_2, [lambda0 lambda0], [minT maxT],'b'); % center wavelength
            plot(app.UIAxes3_2, [a1, a1], [0 maxT],'g'); % attenuation
            plot(app.UIAxes3_2, [a2, a2], [0 maxT],'g'); % attenuation
            % FWHM
            plot(app.UIAxes3_2, [lambda1 lambda1], [minT T(lambda == lambda1)],'m') % First half-max
            plot(app.UIAxes3_2, [lambda2 lambda2], [minT T(lambda == lambda2)],'m') % Second half-max
            
            legend(app.UIAxes3_2,...
                'Transmittance',['Peak Transmittance: ',num2str(maxT),'%'],...
                ['Center Wavlength: ', num2str(lambda0), ' {\mu}m'], 'Attenuation',...
                '','FWHM: 0.065 {\mu}m','','Location','northwest','NumColumns',2);
            
            %

        end

        % Selection changed function: LimittheButtonGroup
        function LimittheButtonGroupSelectionChanged(app, event)
            selectedButton = app.LimittheButtonGroup.SelectedObject;
            
            if selectedButton == app.totalcyclesButton
                app.TotalTimeminEditField.Enable = 'off';
                app.TotalTimeminEditField.Editable = 'off';
                app.SaveeveryEditField.Enable = 'off';
                app.SaveeveryEditField.Editable = 'off';
                
            elseif selectedButton == app.totaltimeButton
                app.TotalCyclesEditField.Enable = 'off';
                app.TotalCyclesEditField.Editable = 'off';
                app.SaveeveryEditField_2.Enable = 'off';
                app.SaveeveryEditField_2.Editable = 'off';
            end
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            ir_folder = app.IRCamDataFolderEditField.Value;
            filename = app.IRCamFileNameEditField.Value;
            try
                v = FlirMovieReader([ir_folder,'\',filename, '_',num2str(app.ats_file_nmbr),'.ats']); % open file
                app.ats_file_nmbr = app.ats_file_nmbr + 1;
            catch
                v = FlirMovieReader([ir_folder, filename, '_',num2str(app.ats_file_nmbr-1),'.ats']); % open file
                warning('Displaying last ATS file; may not be current');
            end
            [frame, ~] = step(v); % read the next frame
            figure(1);
            imshow(frame);
            colormap('parula');
            caxis([min(frame,[],'all') max(frame,[],'all')]);
            
            app.roi1 = images.roi.Ellipse;
            app.roi2 = images.roi.Ellipse;
            app.roi3 = images.roi.Polygon;
            app.roi4 = images.roi.Polygon;
            app.roi5 = images.roi.Polygon;
            app.roi6 = images.roi.Polygon;
            
            draw(app.roi1);draw(app.roi2);draw(app.roi3);
            draw(app.roi4);draw(app.roi5);draw(app.roi6);
            
            app.mask1 = createMask(app.roi1);
            app.mask2 = createMask(app.roi2);
            app.mask3 = createMask(app.roi3);
            app.mask4 = createMask(app.roi4);
            app.mask5 = createMask(app.roi5);
            app.mask6 = createMask(app.roi6);
            mask_1 = app.mask1; roi_1 = app.roi1;
            mask_2 = app.mask2; roi_2 = app.roi2;
            mask_3 = app.mask3; roi_3 = app.roi3;
            mask_4 = app.mask4; roi_4 = app.roi4;
            mask_5 = app.mask5; roi_5 = app.roi5;
            mask_6 = app.mask6; roi_6 = app.roi6;
            
            save('roi.mat','mask_1','mask_2','mask_3',...
                'mask_4','mask_5','mask_6','roi_1','roi_2','roi_3','roi_4','roi_5','roi_6');


        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            % Disable the buttons that shouldn't be pressed while the
            % experiment is running.
            disable_buttons(app);
            
            % Get the COM port number for the USB Relay
            com_nmbr = app.USBRelayDropDown.Value;
            
            % Run some commmands to initialize the relay if it's connected.
            try
                ncd = relay_startup(app,com_nmbr);
            catch
                 warning('No USB Relay Device Detected.')
            end
            
            % Start tracking time.
            time1 = clock;
            app.cycle = 1; % initialize the number of cycles at 1
            total_cycles = app.TotalCyclesEditField.Value;
            total_time = app.TotalTimeminEditField.Value;
            
            % Define where to save the file
            save_file = app.SaveddatafilenameEditField.Value;
            save_fldr = app.EditField_53.Value;
            
            % For transmittance
            tr_fig = animatedline(app.UIAxes2,'MaximumNumPoints',100,'Marker','o');
            % For center wavelength
            cl_fig = animatedline(app.UIAxes3,'MaximumNumPoints',100,'Marker','o');
            
            % For FWHM
            fwhm_fig=animatedline(app.UIAxes5,'MaximumNumPoints',100,'Marker','o');
            
            while ~(strcmp(app.Switch_2.Value, 'Stop'))
                fprintf('cycles = %.5f \n',app.cycle)
                % Trigger the IR camera
                % Comment out if the relay is not connected.
                %relay_trigger(app);
                
                % Calculate and plot the spectral data.
                [lambda0,lambda1,lambda2]=ftir_plotting(app,app.FTIRFileNameEditField.Value);
                cw_fwhm_tr(app,lambda0,lambda1,lambda2,tr_fig,cl_fig,fwhm_fig); % Import FTIR data
%                 if rem(app.cycle,5) == 0
%                 drawnow
%                 end
                
                % Save the data
                save([save_fldr,'\',save_file,'_',num2str(app.cycle),'.mat']);
                if (app.cycle >= total_cycles) || ...
                        (etime(clock, time1) >= total_time*60)
                    break
                end
                app.cycle = app.cycle + 1;
            end
            try
                fclose(ncd);
            catch
                 lastwarn('No USB Relay Device Detected');
            end
            
            app.Switch_2.Value = 'Stop';
        end

        % Value changed function: FoldertoLoadFilesButton
        function FoldertoLoadFilesButtonValueChanged(app, event)
            try
                app.FTIRDataFolderEditField.Value=convertCharsToStrings(uigetdir);
            catch
                app.FTIRDataFolderEditField.Value= pwd;
            end
        end

        % Value changed function: FoldertoLoadFilesButton_2
        function FoldertoLoadFilesButton_2ValueChanged(app, event)
            try
                app.IRCamDataFolderEditField.Value = convertCharsToStrings(uigetdir);
            catch
                app.IRCamDataFolderEditField.Value = pwd;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PulseSettingsTab = uitab(app.TabGroup);
            app.PulseSettingsTab.Title = 'Pulse Settings';
            app.LaserVoltageVEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.LaserVoltageVEditFieldLabel.Text = 'Laser Voltage (V)';
            app.LaserVoltageVEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.LaserVoltageVEditField.Value = 10;
            app.PulseWidthnsEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.PulseWidthnsEditFieldLabel.Text = 'Pulse Width (ns)';
            app.PulseWidthnsEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.PulseWidthnsEditField.Limits = [20 100];
            app.PulseWidthnsEditField.Value = 50;
            app.DelaysLabel = uilabel(app.PulseSettingsTab);
            app.DelaysLabel.Text = 'Delay (μs)';
            app.DelaysEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DelaysEditField.Value = 100;
            app.EditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField.Value = 4;
            app.EditField_2 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_2.Limits = [20 100];
            app.EditField_2.Value = 100;
            app.EditField_3 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_3.Value = 100;
            app.AmorphizationLabel = uilabel(app.PulseSettingsTab);
            app.AmorphizationLabel.Text = 'Amorphization';
            app.CrystallizationLabel = uilabel(app.PulseSettingsTab);
            app.CrystallizationLabel.Text = 'Crystallization';
            app.DutyCycleLabel = uilabel(app.PulseSettingsTab);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DutyCycleEditField.Editable = 'off';
            app.FrequencykHzEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.FrequencykHzEditFieldLabel.Text = 'Frequency (kHz)';
            app.FrequencykHzEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.FrequencykHzEditField.Editable = 'off';
            app.DutyCycleEditField_2 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DutyCycleEditField_2.Editable = 'off';
            app.ConfirmButton = uibutton(app.PulseSettingsTab, 'state');
            app.ConfirmButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonValueChanged, true);
            app.ConfirmButton.Text = 'Confirm';
            app.PeriodsEditFieldLabel = uilabel(app.PulseSettingsTab);
            app.PeriodsEditFieldLabel.Text = 'Period (μs)';
            app.PeriodsEditField = uieditfield(app.PulseSettingsTab, 'numeric');
            app.PeriodsEditField.Editable = 'off';
            app.Image = uiimage(app.PulseSettingsTab);
            app.Image.ImageSource = 'example_phase_change_graph.png';
            app.EditField_46 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_46.Editable = 'off';
            app.EditField_47 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_47.Editable = 'off';
            app.EditField_48 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_48.Value = 1;
            app.EditField_49 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_49.Limits = [20 100];
            app.EditField_49.Value = 100;
            app.EditField_50 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_50.Value = 100;
            app.ReadPulseLabel = uilabel(app.PulseSettingsTab);
            app.ReadPulseLabel.Text = 'Read Pulse';
            app.DutyCycleEditField_3 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.DutyCycleEditField_3.Editable = 'off';
            app.EditField_51 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_51.Editable = 'off';
            app.EditField_52 = uieditfield(app.PulseSettingsTab, 'numeric');
            app.EditField_52.Editable = 'off';
            app.MonitoringTab = uitab(app.TabGroup);
            app.MonitoringTab.Title = 'Monitoring';
            app.ControllerDropDownLabel = uilabel(app.MonitoringTab);
            app.ControllerDropDownLabel.Enable = 'off';
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.MonitoringTab);
            app.ControllerDropDown.Items = {'Disconnected', 'Connected', 'Not Applicable'};
            app.ControllerDropDown.Enable = 'off';
            app.ControllerDropDown.Value = 'Not Applicable';
            app.DCDCConverterDropDownLabel = uilabel(app.MonitoringTab);
            app.DCDCConverterDropDownLabel.Enable = 'off';
            app.DCDCConverterDropDownLabel.Text = 'DC-DC Converter';
            app.DCDCConverterDropDown = uidropdown(app.MonitoringTab);
            app.DCDCConverterDropDown.Items = {'Normal Operation', 'Error', 'Not Available'};
            app.DCDCConverterDropDown.Enable = 'off';
            app.DCDCConverterDropDown.Value = 'Not Available';
            app.PowerConsumptionWGaugeLabel = uilabel(app.MonitoringTab);
            app.PowerConsumptionWGaugeLabel.Enable = 'off';
            app.PowerConsumptionWGaugeLabel.Text = 'Power Consumption (W)';
            app.PowerConsumptionWGauge = uigauge(app.MonitoringTab, 'semicircular');
            app.PowerConsumptionWGauge.Enable = 'off';
            app.HostnameEditFieldLabel = uilabel(app.MonitoringTab);
            app.HostnameEditFieldLabel.Enable = 'off';
            app.HostnameEditFieldLabel.Text = 'Hostname';
            app.HostnameEditField = uieditfield(app.MonitoringTab, 'text');
            app.HostnameEditField.Editable = 'off';
            app.HostnameEditField.Enable = 'off';
            app.HostnameEditField.Value = 'raspberrypi';
            app.RaspberryPiLabel = uilabel(app.MonitoringTab);
            app.RaspberryPiLabel.Enable = 'off';
            app.RaspberryPiLabel.Text = 'Raspberry Pi';
            app.UsernameEditFieldLabel = uilabel(app.MonitoringTab);
            app.UsernameEditFieldLabel.Enable = 'off';
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.MonitoringTab, 'text');
            app.UsernameEditField.Editable = 'off';
            app.UsernameEditField.Enable = 'off';
            app.UsernameEditField.Value = 'pi';
            app.IPAddressEditFieldLabel = uilabel(app.MonitoringTab);
            app.IPAddressEditFieldLabel.Enable = 'off';
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.MonitoringTab, 'text');
            app.IPAddressEditField.Editable = 'off';
            app.IPAddressEditField.Enable = 'off';
            app.IPAddressEditField.Value = '169.254.253.170';
            app.CameraResolutionDropDownLabel = uilabel(app.MonitoringTab);
            app.CameraResolutionDropDownLabel.Enable = 'off';
            app.CameraResolutionDropDownLabel.Text = 'Camera Resolution';
            app.CameraResolutionDropDown = uidropdown(app.MonitoringTab);
            app.CameraResolutionDropDown.Items = {'160x120', '320x240', '640x480', '800x600', '1024x768', '1280x720', '1920x1080'};
            app.CameraResolutionDropDown.Enable = 'off';
            app.CameraResolutionDropDown.Value = '640x480';
            app.TestCameraButton = uibutton(app.MonitoringTab, 'push');
            app.TestCameraButton.ButtonPushedFcn = createCallbackFcn(app, @TestCameraButtonPushed, true);
            app.TestCameraButton.Enable = 'off';
            app.TestCameraButton.Text = 'Test Camera';
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.AmbientTemperatureCLabel = uilabel(app.EnvironmentTab);
            app.AmbientTemperatureCLabel.Enable = 'off';
            app.AmbientTemperatureCLabel.Text = 'Ambient Temperature (°C)';
            app.AmbientTemperatureCGauge = uigauge(app.EnvironmentTab, 'linear');
            app.AmbientTemperatureCGauge.Enable = 'off';
            app.HumidityGaugeLabel = uilabel(app.EnvironmentTab);
            app.HumidityGaugeLabel.Enable = 'off';
            app.HumidityGaugeLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uigauge(app.EnvironmentTab, 'linear');
            app.HumidityGauge.Enable = 'off';
            app.PressurepsiGaugeLabel = uilabel(app.EnvironmentTab);
            app.PressurepsiGaugeLabel.Enable = 'off';
            app.PressurepsiGaugeLabel.Text = 'Pressure (psi)';
            app.PressurepsiGauge = uigauge(app.EnvironmentTab, 'linear');
            app.PressurepsiGauge.Enable = 'off';
            app.InstrumentsTab = uitab(app.TabGroup);
            app.InstrumentsTab.Title = 'Instruments';
            app.USBRelayDropDownLabel = uilabel(app.InstrumentsTab);
            app.USBRelayDropDownLabel.Text = 'USB Relay';
            app.USBRelayDropDown = uidropdown(app.InstrumentsTab);
            app.USBRelayDropDown.Items = {};
            app.USBRelayDropDown.Value = {};
            app.ResourceDropDownLabel = uilabel(app.InstrumentsTab);
            app.ResourceDropDownLabel.Text = 'Resource';
            app.ResourceDropDown = uidropdown(app.InstrumentsTab);
            app.DriverDropDownLabel = uilabel(app.InstrumentsTab);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DriverDropDown = uidropdown(app.InstrumentsTab);
            app.ConnectButton = uibutton(app.InstrumentsTab, 'push');
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.InstrumentsTab, 'push');
            app.DisconnectButton.Text = 'Disconnect';
            app.OscilloscopeChannelDropDownLabel = uilabel(app.InstrumentsTab);
            app.OscilloscopeChannelDropDownLabel.Text = 'Oscilloscope Channel';
            app.OscilloscopeChannelDropDown = uidropdown(app.InstrumentsTab);
            app.FnctnGenChannelDropDownLabel = uilabel(app.InstrumentsTab);
            app.FnctnGenChannelDropDownLabel.Text = 'Fnctn. Gen. Channel';
            app.FnctnGenChannelDropDown = uidropdown(app.InstrumentsTab);
            app.OscVerticalRangeVEditFieldLabel = uilabel(app.InstrumentsTab);
            app.OscVerticalRangeVEditFieldLabel.Text = 'Osc. Vertical Range (V)';
            app.OscVerticalRangeVEditField = uieditfield(app.InstrumentsTab, 'numeric');
            app.AquisitionTimesEditFieldLabel = uilabel(app.InstrumentsTab);
            app.AquisitionTimesEditFieldLabel.Text = 'Aquisition Time (s)';
            app.AquisitionTimesEditField = uieditfield(app.InstrumentsTab, 'numeric');
            app.AutoSetupButton = uibutton(app.InstrumentsTab, 'state');
            app.AutoSetupButton.Text = 'Auto Setup';
            app.TunableFilterReliabilityMeasurementLabel = uilabel(app.LeftPanel);
            app.TunableFilterReliabilityMeasurementLabel.Text = 'Tunable Filter Reliability Measurement';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.MeasurementModeTab = uitab(app.TabGroup2);
            app.MeasurementModeTab.Title = 'Measurement Mode';
            app.TotalCyclesEditFieldLabel = uilabel(app.MeasurementModeTab);
            app.TotalCyclesEditFieldLabel.Text = 'Total Cycles';
            app.TotalCyclesEditField = uieditfield(app.MeasurementModeTab, 'numeric');
            app.TotalCyclesEditField.Value = 100;
            app.TotalTimeminEditFieldLabel = uilabel(app.MeasurementModeTab);
            app.TotalTimeminEditFieldLabel.Text = 'Total Time (min)';
            app.TotalTimeminEditField = uieditfield(app.MeasurementModeTab, 'numeric');
            app.TotalTimeminEditField.Value = 100;
            app.SaveeveryEditFieldLabel = uilabel(app.MeasurementModeTab);
            app.SaveeveryEditFieldLabel.Text = 'Save every';
            app.SaveeveryEditField = uieditfield(app.MeasurementModeTab, 'numeric');
            app.SaveeveryEditField.Value = 10;
            app.cyclesLabel = uilabel(app.MeasurementModeTab);
            app.cyclesLabel.Text = 'cycles';
            app.SaveeveryEditField_2Label = uilabel(app.MeasurementModeTab);
            app.SaveeveryEditField_2Label.Text = 'Save every';
            app.SaveeveryEditField_2 = uieditfield(app.MeasurementModeTab, 'numeric');
            app.SaveeveryEditField_2.Value = 2;
            app.minutesLabel = uilabel(app.MeasurementModeTab);
            app.minutesLabel.Text = 'minutes';
            app.LimittheButtonGroup = uibuttongroup(app.MeasurementModeTab);
            app.LimittheButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LimittheButtonGroupSelectionChanged, true);
            app.LimittheButtonGroup.Title = 'Limit the...';
            app.totalcyclesButton = uiradiobutton(app.LimittheButtonGroup);
            app.totalcyclesButton.Text = 'total cycles';
            app.totalcyclesButton.Value = true;
            app.totaltimeButton = uiradiobutton(app.LimittheButtonGroup);
            app.totaltimeButton.Text = 'total time';
            app.bothButton = uiradiobutton(app.LimittheButtonGroup);
            app.bothButton.Text = 'both';
            app.StopConditionTab = uitab(app.TabGroup2);
            app.StopConditionTab.Title = 'Stop Condition';
            app.ofoccurancesoutsideoflimitCheckBox = uicheckbox(app.StopConditionTab);
            app.ofoccurancesoutsideoflimitCheckBox.Text = '% of occurances outside of limit';
            app.NumberofoccurancesoutsideoflimitCheckBox = uicheckbox(app.StopConditionTab);
            app.NumberofoccurancesoutsideoflimitCheckBox.Text = 'Number of occurances outside of limit';
            app.AmountoutsideoflimitCheckBox = uicheckbox(app.StopConditionTab);
            app.AmountoutsideoflimitCheckBox.Text = 'Amount outside of limit';
            app.Label = uilabel(app.StopConditionTab);
            app.Label.Text = '%';
            app.EditField_4 = uieditfield(app.StopConditionTab, 'numeric');
            app.EditField_4Label_2 = uilabel(app.StopConditionTab);
            app.EditField_4Label_2.Text = 'times';
            app.EditField_5 = uieditfield(app.StopConditionTab, 'numeric');
            app.EditField_6 = uieditfield(app.StopConditionTab, 'numeric');
            app.DropDown = uidropdown(app.StopConditionTab);
            app.DropDown.Items = {'%', 'nm'};
            app.DropDown.Editable = 'on';
            app.DropDown.Value = '%';
            app.TransmittanceCheckBox = uicheckbox(app.StopConditionTab);
            app.TransmittanceCheckBox.Text = 'Transmittance';
            app.CenterWavelengthCheckBox = uicheckbox(app.StopConditionTab);
            app.CenterWavelengthCheckBox.Text = 'Center Wavelength';
            app.FWHMCheckBox = uicheckbox(app.StopConditionTab);
            app.FWHMCheckBox.Text = 'FWHM';
            app.StopbasedonLabel = uilabel(app.StopConditionTab);
            app.StopbasedonLabel.Text = 'Stop based on....';
            app.LimitsTab = uitab(app.TabGroup2);
            app.LimitsTab.Title = 'Limits';
            app.GridLayout2_2 = uigridlayout(app.LimitsTab);
            app.AmorphizationLabel_3 = uilabel(app.GridLayout2_2);
            app.AmorphizationLabel_3.Text = 'Amorphization';
            app.CrystalizationLabel_2 = uilabel(app.GridLayout2_2);
            app.CrystalizationLabel_2.Text = 'Crystalization';
            app.EditField_34 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_35 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_36 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_37 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_38 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_39 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_40 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_41 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_42 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_43 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_44 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_45 = uieditfield(app.GridLayout2_2, 'numeric');
            app.MeasuredLabel_2 = uilabel(app.GridLayout2_2);
            app.MeasuredLabel_2.Text = 'Measured';
            app.AverageLabel_3 = uilabel(app.GridLayout2_2);
            app.AverageLabel_3.Text = 'Average';
            app.LimitLabel_2 = uilabel(app.GridLayout2_2);
            app.LimitLabel_2.Text = 'Limit +/-';
            app.AverageLabel_4 = uilabel(app.GridLayout2_2);
            app.AverageLabel_4.Text = 'Average';
            app.DeviationLabel_2 = uilabel(app.GridLayout2_2);
            app.DeviationLabel_2.Text = 'Deviation';
            app.MaxLabel_2 = uilabel(app.GridLayout2_2);
            app.MaxLabel_2.Text = 'Max.';
            app.MinLabel_2 = uilabel(app.GridLayout2_2);
            app.MinLabel_2.Text = 'Min.';
            app.ConfigurationLabel_2 = uilabel(app.GridLayout2_2);
            app.ConfigurationLabel_2.Text = 'Configuration';
            app.TestingTab = uitab(app.TabGroup2);
            app.TestingTab.Title = 'Testing';
            app.Switch = uiswitch(app.TestingTab, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.GetFTIRdataButton = uibutton(app.TestingTab, 'push');
            app.GetFTIRdataButton.ButtonPushedFcn = createCallbackFcn(app, @GetFTIRdataButtonPushed, true);
            app.GetFTIRdataButton.Text = 'Get FTIR data';
            app.TransmittanceEditFieldLabel = uilabel(app.TestingTab);
            app.TransmittanceEditFieldLabel.Text = 'Transmittance';
            app.TransmittanceEditField = uieditfield(app.TestingTab, 'numeric');
            app.TransmittanceEditField.Editable = 'off';
            app.FilesTab = uitab(app.TabGroup2);
            app.FilesTab.Title = 'Files';
            app.FoldertoSaveFilesButton = uibutton(app.FilesTab, 'state');
            app.FoldertoSaveFilesButton.ValueChangedFcn = createCallbackFcn(app, @ChooseFileLocationButtonValueChanged, true);
            app.FoldertoSaveFilesButton.Enable = 'off';
            app.FoldertoSaveFilesButton.Text = 'Folder to Save Files';
            app.EditField_53 = uieditfield(app.FilesTab, 'text');
            app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @ChooseFileLocationButtonValueChanged, true);
            app.EditField_53.Editable = 'off';
            app.FTIRDataFolderEditField = uieditfield(app.FilesTab, 'text');
            app.FTIRDataFolderEditField.Editable = 'off';
            app.FTIRFileNameEditField = uieditfield(app.FilesTab, 'text');
            app.SaveddatafilenameEditFieldLabel = uilabel(app.FilesTab);
            app.SaveddatafilenameEditFieldLabel.Text = 'Saved data file name';
            app.SaveddatafilenameEditField = uieditfield(app.FilesTab, 'text');
            app.SaveddatafilenameEditField.Value = 'test_data';
            app.FoldertoLoadFilesButton = uibutton(app.FilesTab, 'state');
            app.FoldertoLoadFilesButton.ValueChangedFcn = createCallbackFcn(app, @FoldertoLoadFilesButtonValueChanged, true);
            app.FoldertoLoadFilesButton.Enable = 'off';
            app.FoldertoLoadFilesButton.Text = 'Folder to Load Files';
            app.FoldertoLoadFilesButton_2 = uibutton(app.FilesTab, 'state');
            app.FoldertoLoadFilesButton_2.ValueChangedFcn = createCallbackFcn(app, @FoldertoLoadFilesButton_2ValueChanged, true);
            app.FoldertoLoadFilesButton_2.Enable = 'off';
            app.FoldertoLoadFilesButton_2.Text = 'Folder to Load Files';
            app.IRCamDataFolderEditField = uieditfield(app.FilesTab, 'text');
            app.IRCamDataFolderEditField.Editable = 'off';
            app.IRCamFileNameEditField = uieditfield(app.FilesTab, 'text');
            app.SaveddatafilenameEditFieldLabel_2 = uilabel(app.FilesTab);
            app.SaveddatafilenameEditFieldLabel_2.Text = 'FTIR Data File Name';
            app.SaveddatafilenameEditFieldLabel_3 = uilabel(app.FilesTab);
            app.SaveddatafilenameEditFieldLabel_3.Text = 'IR Camera File Name';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Items = {'Stop', 'Start'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Stop';
            app.DrawROIButton = uibutton(app.LeftPanel, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI ';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.TransmittanceTab = uitab(app.TabGroup3);
            app.TransmittanceTab.Title = 'Transmittance';
            app.UIAxes2 = uiaxes(app.TransmittanceTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Number of Cycles')
            ylabel(app.UIAxes2, 'Transmittance (%)')
            app.UIAxes2.XScale = 'log';
            app.CenterWavelengthTab = uitab(app.TabGroup3);
            app.CenterWavelengthTab.Title = 'Center Wavelength';
            app.UIAxes3 = uiaxes(app.CenterWavelengthTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'Number of Cycles')
            ylabel(app.UIAxes3, 'Center Wavelength (nm)')
            app.UIAxes3.XScale = 'log';
            app.FWHMTab = uitab(app.TabGroup3);
            app.FWHMTab.Title = 'FWHM';
            app.UIAxes5 = uiaxes(app.FWHMTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, 'Cycle Count')
            ylabel(app.UIAxes5, 'FWHM (um)')
            app.UIAxes5.XScale = 'log';
            app.FTIRTab = uitab(app.TabGroup3);
            app.FTIRTab.Title = 'FTIR';
            app.UIAxes3_2 = uiaxes(app.FTIRTab);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, 'Number of Cycles')
            ylabel(app.UIAxes3_2, 'Transmittance (%)')
            app.UIAxes3_2.XLim = [1 1000];
            app.UIAxes3_2.YLim = [0 20];
            app.UIAxes3_2.XScale = 'log';
            app.TabGroup4 = uitabgroup(app.RightPanel);
            app.LaserOscFGenTab = uitab(app.TabGroup4);
            app.LaserOscFGenTab.Title = 'Laser (Osc/FGen)';
            app.UIAxes = uiaxes(app.LaserOscFGenTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.IRImageTab = uitab(app.TabGroup4);
            app.IRImageTab.Title = 'IR Image';
            app.UIAxes4 = uiaxes(app.IRImageTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end